classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout1             matlab.ui.container.GridLayout
        Panel1                  matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        FromURLEditFieldLabel   matlab.ui.control.Label
        UrlField                matlab.ui.control.EditField
        PickImageDropDownLabel  matlab.ui.control.Label
        PickImageDropDown       matlab.ui.control.DropDown
        LoadURLButton           matlab.ui.control.Button
        FromPCButton            matlab.ui.control.Button
        ResultsPanel            matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Label_T                 matlab.ui.control.Label
        ElapsedTimeLabel        matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ConfidenceLabel         matlab.ui.control.Label
        GUESSLabel              matlab.ui.control.Label
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        current_image; %
        current_time;
        % Modes of loading pictures // URL // LOCAL STORAGE 
        MODE_URL = 0;
        MODE_LOCAL = 1;
        
    end
   
    methods(Static) 
        function  includePath()
            addpath("../lib/");
            addpath("../images");
            addpath("../images/tempelates");
        end
    end
    
    methods (Access = private)
        
        
        function setUiAxes(app)
            axs = [app.UIAxes1 app.UIAxes2 app.UIAxes3 app.UIAxes4];
            titles = ["Original Image" "Masked Image" "Segmented Image" "Final"];
            
            for i=1:4
                axs(i).XTick = [];
                axs(i).YTick = [];
                xlabel(axs(i), '');
                ylabel(axs(i), '');
                title(axs(i), titles(i));
            end
        end       

        function setDropDownDialog(app)
            options = strings(1,10);
            handels = strings(1,10);
            for i=1:10
                options(i) = "im" + num2str(i);
                handels(i) = "im" + num2str(i) + ".png";
            end
            app.PickImageDropDown.Items = options;
            app.PickImageDropDown.ItemsData = handels;
        end


        function update_gui(app)
            tic;
            try
            imshow(app.current_image,'Parent',app.UIAxes1);
            img = filter_bright(app.current_image);
            imshow(img,'Parent',app.UIAxes2);
            img = compartment(img);
            imshow(img,'Parent',app.UIAxes3)
            img = isolate_digits(img);
            catch ME
                    uialert(app.UIFigure, ME.message, 'Segmentation Error');
                    return;
            end
            try
            img = deskew_image(img);
            final = snip(img);
             catch ME
                 uialert(app.UIFigure, ME.message, 'Angle Correction Error / Cropping');
                    return;
            end
            try
            imshow(final,'Parent',app.UIAxes4);
            [T,C] = guess_time(final);
            app.current_time = num2str(reshape(T', 1, []));
            app.Label.Text = app.current_time;
            app.Label_2.Text = num2str(round(C*100)) + "%";
            catch ME
                uialert(app.UIFigure, ME.message, 'Classification Error');
                    return;
            end
            toc;
            elapsed_time = num2str(round(toc*1000)) + "ms";
            app.Label_T.Text = elapsed_time;
        end


        function updateImage(app,mode)
            
            if mode == app.MODE_LOCAL
                try
                app.current_image = read_image(app.PickImageDropDown.Value);
                catch ME
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end         
            elseif mode == app.MODE_URL
                try
                app.current_image = imread(app.UrlField.Value);
                catch ME
                    uialert(app.UIFigure, ME.message, 'Image Error / EMPTY OR INCORRECT URL');
                    return;
                end            
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.includePath();
            app.setDropDownDialog();
            app.setUiAxes();
            app.updateImage(app.MODE_LOCAL);
            app.update_gui();
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Value changed function: PickImageDropDown
        function PickImageDropDownValueChanged2(app, event)
            app.updateImage(app.MODE_LOCAL)
            app.update_gui();
            
        end

        % Button pushed function: LoadURLButton
        function LoadURLButtonPushed(app, event)
            if app.UrlField.Value == ""
                uialert(app.UIFigure, "Empty URL, please insert a valid URL First", 'Image Error');
                return;
            end
            app.updateImage(app.MODE_URL);
            app.update_gui();
        end

        % Button pushed function: FromPCButton
        function FromPCButtonPushed(app, event)
                           
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               app.current_image = imread(fname);
               app.update_gui();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout1 = uigridlayout(app.UIFigure);
            app.UIAxes1 = uiaxes(app.GridLayout1);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout1);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout1);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout1);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ResultsPanel = uipanel(app.GridLayout1);
            app.ResultsPanel.Title = 'Results';
            app.GridLayout3 = uigridlayout(app.ResultsPanel);
            app.GUESSLabel = uilabel(app.GridLayout3);
            app.GUESSLabel.Text = 'GUESS';
            app.ConfidenceLabel = uilabel(app.GridLayout3);
            app.ConfidenceLabel.Text = 'Confidence';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '';
            app.ElapsedTimeLabel = uilabel(app.GridLayout3);
            app.ElapsedTimeLabel.Text = 'Elapsed Time';
            app.Label_T = uilabel(app.GridLayout3);
            app.Label_T.Text = '';
            app.Panel1 = uipanel(app.GridLayout1);
            app.Panel1.Title = 'Controls';
            app.GridLayout4 = uigridlayout(app.Panel1);
            app.FromPCButton = uibutton(app.GridLayout4, 'push');
            app.FromPCButton.ButtonPushedFcn = createCallbackFcn(app, @FromPCButtonPushed, true);
            app.FromPCButton.Icon = 'icon1.png';
            app.FromPCButton.Text = 'From PC';
            app.LoadURLButton = uibutton(app.GridLayout4, 'push');
            app.LoadURLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadURLButtonPushed, true);
            app.LoadURLButton.Icon = 'icon2.png';
            app.LoadURLButton.Text = 'Load URL';
            app.PickImageDropDown = uidropdown(app.GridLayout4);
            app.PickImageDropDown.ValueChangedFcn = createCallbackFcn(app, @PickImageDropDownValueChanged2, true);
            app.PickImageDropDownLabel = uilabel(app.GridLayout4);
            app.PickImageDropDownLabel.Text = 'Pick Image';
            app.UrlField = uieditfield(app.GridLayout4, 'text');
            app.FromURLEditFieldLabel = uilabel(app.GridLayout4);
            app.FromURLEditFieldLabel.Text = 'From URL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end